classdef audiofilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        Ws1             matlab.ui.control.NumericEditField
        Ws2Label        matlab.ui.control.Label
        Wp1             matlab.ui.control.NumericEditField
        Wp2Label_2      matlab.ui.control.Label
        As              matlab.ui.control.NumericEditField
        AsLabel         matlab.ui.control.Label
        Ap              matlab.ui.control.NumericEditField
        ApLabel         matlab.ui.control.Label
        Ws              matlab.ui.control.NumericEditField
        Ws1Label        matlab.ui.control.Label
        Wp              matlab.ui.control.NumericEditField
        Wp1Label        matlab.ui.control.Label
        Button_17       matlab.ui.control.Button
        NOTICETextArea  matlab.ui.control.TextArea
        NOTICELabel     matlab.ui.control.Label
        Button_16       matlab.ui.control.Button
        Button_15       matlab.ui.control.Button
        TextArea_5      matlab.ui.control.TextArea
        ButtonGroup_2   matlab.ui.container.ButtonGroup
        Button_14       matlab.ui.control.RadioButton
        Button_13       matlab.ui.control.RadioButton
        Button_12       matlab.ui.control.RadioButton
        Button_11       matlab.ui.control.RadioButton
        ButtonGroup     matlab.ui.container.ButtonGroup
        Button_10       matlab.ui.control.RadioButton
        Button_9        matlab.ui.control.RadioButton
        Button_8        matlab.ui.control.RadioButton
        Button_7        matlab.ui.control.RadioButton
        TextArea_4      matlab.ui.control.TextArea
        Button_6        matlab.ui.control.Button
        Button_5        matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        TextArea_3      matlab.ui.control.TextArea
        Button_3        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        TextArea_2      matlab.ui.control.TextArea
        TextArea_8      matlab.ui.control.TextArea
        UIAxes2         matlab.ui.control.UIAxes
        RightPanel      matlab.ui.container.Panel
        UIAxes_6        matlab.ui.control.UIAxes
        UIAxes_5        matlab.ui.control.UIAxes
        UIAxes_4        matlab.ui.control.UIAxes
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global Y;
            global FS;
            [filename,path] = uigetfile({'*.mp3'},'选择音频文件');
            file=[path filename];
            [Y,FS]=audioread(file);
            Y=Y(:,1);
            app.NOTICETextArea.Value=filename;
            % 绘制原信号时域
            plot(app.UIAxes,Y);
            axis(app.UIAxes,[0 length(Y) min(Y)-0.5 max(Y)+0.5]);
            % 绘制原信号频域
            L=length(Y);
            y=abs(fft(Y,L));
            k=0:L-1;
            D=FS/L;
            plot(app.UIAxes_2,k*D,y);
            axis(app.UIAxes_2,[0 10000 0 max(y)]);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global Y;
            global FS;
            global YMix;
            L=length(Y);
            k=0:L-1;
            % 添加噪音
            noise=0.01*sin(3*pi*1500*k/FS);
            noise=noise';
            YMix=Y+noise;
            % 绘制时域
            plot(app.UIAxes_3,YMix);
            axis(app.UIAxes_3,[0 length(YMix) min(YMix)-0.5 max(YMix)+0.5]);
            % 绘制频域
            L=length(YMix);
            ymix=abs(fft(YMix,L));
            k=0:L-1;
            D=FS/L;
            plot(app.UIAxes_4,k*D,ymix);
            axis(app.UIAxes_4,[0 10000 0 max(ymix)]);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            global Y;
            global FS;
            R=audiorecorder(44100,16,1);
            FS=44100;
            record(R);
            app.NOTICETextArea.Value='开始录音，五秒后停止';
            pause(5);
            stop(R);
            app.NOTICETextArea.Value='录音结束';
            Y = getaudiodata(R);
            % 绘制原信号时域
            plot(app.UIAxes,Y);
            axis(app.UIAxes,[0 length(Y) min(Y)-0.5 max(Y)+0.5]);
            % 绘制原信号频域
            L=length(Y);
            y=abs(fft(Y,L));
            k=0:L-1;
            D=FS/L;
            plot(app.UIAxes_2,k*D,y);
            axis(app.UIAxes_2,[0 10000 0 max(y)]);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
           % 播放原始音频
           global Y;
           global FS;
           sound(Y,FS);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            % 停止播放
            clear sound;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             % 播放加噪声音频
            global YMix;
            global FS;
            sound(YMix,FS);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global filter1
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.Button_7
                filter1=1;
            elseif selectedButton==app.Button_8
                filter1=2;
            elseif selectedButton==app.Button_9
                filter1=3;
            else
                filter1=4;
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            global filter2
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if selectedButton==app.Button_11
                filter2=1;
            elseif selectedButton==app.Button_12
                filter2=2;
            elseif selectedButton==app.Button_13
                filter2=3;
            else
                filter2=4;
            end
        end

        % Callback function
        function WpEditFieldValueChanged(app, event)

        end

        % Callback function
        function WsEditFieldValueChanged(app, event)

        end

        % Callback function
        function APEditFieldValueChanged(app, event)

        end

        % Callback function
        function AsEditFieldValueChanged(app, event)

        end

        % Callback function
        function SwitchValueChanged(app, event)
    
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            global Wp;
            global Wp2;
            global Ws;
            global Ws2;
            global Ap;
            global As; 
            global filter1;
            global filter2; % 滤波器类型参数
            global Y; % 原始信号
            global FS; 
            global YMix; %加噪声信号
            global Yf;
            wp1=Wp/FS*2*pi;
            ws1=Ws/FS*2*pi;
            wp2=Wp2/FS*2*pi;
            ws2=Ws2/FS*2*pi;
            assignin("base","Wp",Wp);
            assignin("base","Ws",Ws);
            assignin("base","wp1",wp1);
            assignin("base","wp2",wp2);
            assignin("base","ws1",ws1);
            assignin("base","ws2",ws2);
            if filter1==1 % 巴特沃斯
                if filter2==1 % 低通
                    [N,Wc]=buttord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=butter(N,Wc);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               elseif filter2==2 % 高通
                    [N,Wc]=buttord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=butter(N,Wc,'high');
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
                elseif filter2==3 % 带通
                    wp=[wp1,wp2];
                    ws=[ws1,ws2];
                    [N,Wc]=buttord(wp/pi,ws/pi,Ap,As);
                    [B,A]=butter(N,Wc);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               else % 带阻
                   wp=[wp1,wp2];
                   ws=[ws1,ws2];
                   [N,Wc]=buttord(wp/pi,ws/pi,Ap,As);
                   [B,A]=butter(N,Wc,'stop');  
                   Yf=filter(B,A,YMix);
                   plot(app.UIAxes_5,Yf);
                   % 绘制频域
                   L=length(Y);
                   yf=abs(fft(Yf,L));
                   k=0:L-1;
                   D=FS/L;
                   plot(app.UIAxes_6,k*D,yf);
                   axis(app.UIAxes_6,[0 10000 0 6000]) ;
                   % 绘制滤波器增益响应
                   [H,W]=freqz(B,A);
                   dbH=20*log10(abs(H)/max(abs(H))); 
                   plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                   axis(app.UIAxes2,[0,6000 -100 100]) ;
                end
                
            elseif filter1==2 % 切比雪夫Ⅰ
                if filter2==1 % 低通
                    [N,Wc]=cheb1ord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=cheby1(N,Ap,Wc);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               elseif filter2==2 % 高通
                    [N,Wc]=cheb1ord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=cheby1(N,Ap,Wc,'high');
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
                elseif filter2==3 % 带通
                    wp=[wp1,wp2];
                    ws=[ws1,ws2];
                    [N,Wc]=cheb1ord(wp/pi,ws/pi,Ap,As);
                    [B,A]=cheby1(N,Ap,Wc);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               else % 带阻
                   wp=[wp1,wp2];
                   ws=[ws1,ws2];
                   [N,Wc]=cheb1ord(wp/pi,ws/pi,Ap,As);
                   [B,A]=cheby1(N,Ap,Wc,'stop');  
                   Yf=filter(B,A,YMix);
                   plot(app.UIAxes_5,Yf);
                   % 绘制频域
                   L=length(Y);
                   yf=abs(fft(Yf,L));
                   k=0:L-1;
                   D=FS/L;
                   plot(app.UIAxes_6,k*D,yf);
                   axis(app.UIAxes_6,[0 10000 0 6000]) ;
                   % 绘制滤波器增益响应
                   [H,W]=freqz(B,A);
                   dbH=20*log10(abs(H)/max(abs(H))); 
                   plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                   axis(app.UIAxes2,[0,6000 -100 100]) ;
                end
            elseif filter1==3 % 切比雪夫Ⅱ
                if filter2==1 % 低通
                    [N,Wc]=cheb2ord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=cheby2(N,As,Wc);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               elseif filter2==2 % 高通
                    [N,Wc]=cheb1ord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=cheby1(N,As,Wc,'high');
                    [H,W]=freqz(B,A);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
                elseif filter2==3 % 带通
                    wp=[wp1,wp2];
                    ws=[ws1,ws2];
                    [N,Wc]=cheb1ord(wp/pi,ws/pi,Ap,As);
                    [B,A]=cheby1(N,As,Wc);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               else % 带阻
                   wp=[wp1,wp2];
                   ws=[ws1,ws2];
                   [N,Wc]=cheb1ord(wp/pi,ws/pi,Ap,As);
                   [B,A]=cheby1(N,As,Wc,'stop');  
                   Yf=filter(B,A,YMix);
                   plot(app.UIAxes_5,Yf);
                   % 绘制频域
                   L=length(Y);
                   yf=abs(fft(Yf,L));
                   k=0:L-1;
                   D=FS/L;
                   plot(app.UIAxes_6,k*D,yf);
                   axis(app.UIAxes_6,[0 10000 0 6000]) ;
                   % 绘制滤波器增益响应
                   [H,W]=freqz(B,A);
                   dbH=20*log10(abs(H)/max(abs(H))); 
                   plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                   axis(app.UIAxes2,[0,6000 -100 100]) ;
                end
                
            else % 椭圆
                if filter2==1 % 低通
                    [N,Wc]=ellipord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=ellip(N,Ap,As,Wc);
                    [H,W]=freqz(B,A);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               elseif filter2==2 % 高通
                    [N,Wc]=ellipord(wp1/pi,ws1/pi,Ap,As);
                    [B,A]=ellip(N,Ap,As,Wc,'high');
                    [H,W]=freqz(B,A);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
                elseif filter2==3 % 带通
                    wp=[wp1,wp2];
                    ws=[ws1,ws2];
                    [N,Wc]=ellipord(wp/pi,ws/pi,Ap,As);
                    [B,A]=ellip(N,Ap,As,Wc);
                    Yf=filter(B,A,YMix);
                    plot(app.UIAxes_5,Yf);
                    % 绘制频域
                    L=length(Y);
                    yf=abs(fft(Yf,L));
                    k=0:L-1;
                    D=FS/L;
                    plot(app.UIAxes_6,k*D,yf);
                    axis(app.UIAxes_6,[0 10000 0 6000]) ;
                    % 绘制滤波器增益响应
                    [H,W]=freqz(B,A);
                    dbH=20*log10(abs(H)/max(abs(H))); 
                    plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                    axis(app.UIAxes2,[0,6000 -100 100]) ;
               else % 带阻
                   wp=[wp1,wp2];
                   ws=[ws1,ws2];
                   [N,Wc]=ellipord(wp/pi,ws/pi,Ap,As);
                   [B,A]=ellip(N,Ap,As,Wc,'stop');  
                   Yf=filter(B,A,YMix);
                   plot(app.UIAxes_5,Yf);
                   % 绘制频域
                   L=length(Y);
                   yf=abs(fft(Yf,L));
                   k=0:L-1;
                   D=FS/L;
                   plot(app.UIAxes_6,k*D,yf);
                   axis(app.UIAxes_6,[0 10000 0 6000]) ;
                   % 绘制滤波器增益响应
                   [H,W]=freqz(B,A);
                   dbH=20*log10(abs(H)/max(abs(H))); 
                   plot(app.UIAxes2,W/2/pi*FS,dbH,'r'); 
                   axis(app.UIAxes2,[0,6000 -100 100]) ;
                end
            end
        end

        % Callback function
        function WpEditFieldValueChanged2(app, event)
    
        end

        % Callback function
        function WsEditFieldValueChanged2(app, event)

        end

        % Callback function
        function ApEditFieldValueChanged(app, event)

        end

        % Callback function
        function AsEditFieldValueChanged2(app, event)

        end

        % Value changed function: Wp
        function WpValueChanged(app, event)
            global Wp;
            Wp = app.Wp.Value;
        end

        % Value changed function: Ws
        function WsValueChanged(app, event)
            global Ws;
            Ws = app.Ws.Value;
        end

        % Value changed function: Ap
        function ApValueChanged(app, event)
            global Ap;
            Ap = app.Ap.Value;
        end

        % Value changed function: As
        function AsValueChanged(app, event)
            global As;
            As = app.As.Value;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 播放滤波后音频
            global Yf;
            global FS;
            sound(Yf,FS);
        end

        % Value changed function: Wp1
        function Wp1ValueChanged(app, event)
            global Wp2;
            Wp2 = app.Wp1.Value;           
        end

        % Value changed function: Ws1
        function Ws1ValueChanged(app, event)
            global Ws2;
            Ws2 = app.Ws1.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {897, 897};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {835, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, '滤波器增益响应')
            xlabel(app.UIAxes2, '频率（Hz）')
            ylabel(app.UIAxes2, '增益（dB）')
            zlabel(app.UIAxes2, 'Z')
            app.TextArea_8 = uitextarea(app.LeftPanel);
            app.TextArea_2 = uitextarea(app.LeftPanel);
            app.TextArea_2.Value = {'音频'};
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'选择音频'; ''};
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '添加噪声';
            app.TextArea_3 = uitextarea(app.LeftPanel);
            app.TextArea_3.Value = {'播放'};
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '播放原始音频';
            app.Button_5 = uibutton(app.LeftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '播放加噪声音频';
            app.Button_6 = uibutton(app.LeftPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '播放滤波后音频';
            app.TextArea_4 = uitextarea(app.LeftPanel);
            app.TextArea_4.Value = {'IIR滤波器'};
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '滤波器种类';
            app.Button_7 = uiradiobutton(app.ButtonGroup);
            app.Button_7.Text = '巴特沃斯滤波器';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.ButtonGroup);
            app.Button_8.Text = '切比雪夫Ⅰ型滤波器';
            app.Button_9 = uiradiobutton(app.ButtonGroup);
            app.Button_9.Text = '切比雪夫Ⅱ型滤波器';
            app.Button_10 = uiradiobutton(app.ButtonGroup);
            app.Button_10.Text = '椭圆滤波器';
            app.ButtonGroup_2 = uibuttongroup(app.LeftPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '滤波器类型';
            app.Button_11 = uiradiobutton(app.ButtonGroup_2);
            app.Button_11.Text = '低通滤波器';
            app.Button_11.Value = true;
            app.Button_12 = uiradiobutton(app.ButtonGroup_2);
            app.Button_12.Text = '高通滤波器';
            app.Button_13 = uiradiobutton(app.ButtonGroup_2);
            app.Button_13.Text = '带通滤波器';
            app.Button_14 = uiradiobutton(app.ButtonGroup_2);
            app.Button_14.Text = '带阻滤波器';
            app.TextArea_5 = uitextarea(app.LeftPanel);
            app.TextArea_5.Value = {'性能指标'};
            app.Button_15 = uibutton(app.LeftPanel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '停止播放';
            app.Button_16 = uibutton(app.LeftPanel, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '录制音频';
            app.NOTICELabel = uilabel(app.LeftPanel);
            app.NOTICELabel.Text = 'NOTICE：';
            app.NOTICETextArea = uitextarea(app.LeftPanel);
            app.Button_17 = uibutton(app.LeftPanel, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '滤波';
            app.Wp1Label = uilabel(app.LeftPanel);
            app.Wp1Label.Text = 'Wp1';
            app.Wp = uieditfield(app.LeftPanel, 'numeric');
            app.Wp.ValueChangedFcn = createCallbackFcn(app, @WpValueChanged, true);
            app.Wp.Value = 1500;
            app.Ws1Label = uilabel(app.LeftPanel);
            app.Ws1Label.Text = 'Ws1';
            app.Ws = uieditfield(app.LeftPanel, 'numeric');
            app.Ws.ValueChangedFcn = createCallbackFcn(app, @WsValueChanged, true);
            app.Ws.Value = 1750;
            app.ApLabel = uilabel(app.LeftPanel);
            app.ApLabel.Text = 'Ap';
            app.Ap = uieditfield(app.LeftPanel, 'numeric');
            app.Ap.ValueChangedFcn = createCallbackFcn(app, @ApValueChanged, true);
            app.Ap.Value = 1;
            app.AsLabel = uilabel(app.LeftPanel);
            app.AsLabel.Text = 'As';
            app.As = uieditfield(app.LeftPanel, 'numeric');
            app.As.ValueChangedFcn = createCallbackFcn(app, @AsValueChanged, true);
            app.As.Value = 40;
            app.Wp2Label_2 = uilabel(app.LeftPanel);
            app.Wp2Label_2.Text = 'Wp2';
            app.Wp1 = uieditfield(app.LeftPanel, 'numeric');
            app.Wp1.ValueChangedFcn = createCallbackFcn(app, @Wp1ValueChanged, true);
            app.Wp1.Value = 2100;
            app.Ws2Label = uilabel(app.LeftPanel);
            app.Ws2Label.Text = 'Ws2';
            app.Ws1 = uieditfield(app.LeftPanel, 'numeric');
            app.Ws1.ValueChangedFcn = createCallbackFcn(app, @Ws1ValueChanged, true);
            app.Ws1.Value = 1900;
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = '音频处理系统';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '原始音频时域')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '幅度')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '原始音频频域')
            xlabel(app.UIAxes_2, '频率')
            ylabel(app.UIAxes_2, '幅度')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, '加噪音音频时域')
            xlabel(app.UIAxes_3, '时间')
            ylabel(app.UIAxes_3, '幅度')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, '加噪音音频频域')
            xlabel(app.UIAxes_4, '时间')
            ylabel(app.UIAxes_4, '幅度')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, '滤波后音频时域')
            xlabel(app.UIAxes_5, '时间')
            ylabel(app.UIAxes_5, '幅度')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.RightPanel);
            title(app.UIAxes_6, '滤波后音频频域')
            xlabel(app.UIAxes_6, '时间')
            ylabel(app.UIAxes_6, '幅度')
            zlabel(app.UIAxes_6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiofilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end